classdef VisAnaGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        ReadTab                matlab.ui.container.Tab
        SelectFilesButton      matlab.ui.control.Button
        runlogTextAreaLabel    matlab.ui.control.Label
        runlogTextArea         matlab.ui.control.TextArea
        FileDirectory          matlab.ui.control.EditField
        TabPlot                matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        Label                  matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        yminEditFieldLabel     matlab.ui.control.Label
        yminEditField          matlab.ui.control.NumericEditField
        xminEditFieldLabel     matlab.ui.control.Label
        xminEditField          matlab.ui.control.NumericEditField
        xmaxEditField_2Label   matlab.ui.control.Label
        xmaxEditField          matlab.ui.control.NumericEditField
        ymaxEditFieldLabel     matlab.ui.control.Label
        ymaxEditField          matlab.ui.control.NumericEditField
        autoredrawCheckBox     matlab.ui.control.CheckBox
        PlotButton             matlab.ui.control.Button
        userrangeCheckBox      matlab.ui.control.CheckBox
        plotvarEditFieldLabel  matlab.ui.control.Label
        plotvarEditField       matlab.ui.control.EditField
        plotmodeDropDownLabel  matlab.ui.control.Label
        plotmodeDropDown       matlab.ui.control.DropDown
        PlottypeButtonGroup    matlab.ui.container.ButtonGroup
        Plottype1D             matlab.ui.control.ToggleButton
        Plottype2D             matlab.ui.control.ToggleButton
        Plottype3D             matlab.ui.control.ToggleButton
        ipictLabel             matlab.ui.control.Label
        ipictSlider            matlab.ui.control.Slider
        TabData                matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
    end

    % The app displays the data by using the scatter plot and histogram.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch and radiobutton group.
    % The data used in the app is shiped with the product.
    
    properties (Access = private)
        % Declare properties of the file class.
        filename
        filehead
        Data
        func         % plotting function names
        plotmode     
        plotrange    % plotting range for 2D
        plotinterval % interval for interpolation
        ipict        % index of snapshot
        filelist % Description
        pathname % Description
    end
    
    methods (Access = private)
        
        function refreshslider(app, event)
           if app.filelist.npictinfiles > 1
              app.ipictSlider.Limits = [1 app.filelist.npictinfiles];
           end
        end
       
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %disp('Now we start')
            %refreshplot(app)
        end

        % Cell selection callback: UITable
        function refreshplot(app, event)
            %Colors = app.SelectedColors;

            % Start with a fresh plot
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
                           
        end

        % Callback function: FileDirectory, SelectFilesButton, 
        % runlogTextArea
        function ReadData(app, event)
           [app.filename, app.pathname, filterindex] = uigetfile( ...
              {  '*.log;*.dat;*.out;*.outs','Output Files (*.log,*.dat,*.out,*.outs)';...
              '*.log;*.dat', 'ASCII files (*.log,*.dat)';...
              '*.out;*.outs', 'Binary files (*.out,*.outs)';...
              '*.*','All Files (*.*)'}, ...
              'Pick a file', ...
              'MultiSelect', 'on');


           if app.filename~=0
              %diary commandwindowOutput.log
              %[app.filehead,app.Data] = read_data(strcat(pathname,app.filename));
              %diary off
              %info = string(fileread('commandwindowOutput.log'));
              [T,app.filehead,app.Data,app.filelist] = ...
                  evalc('read_data(strcat(app.pathname,app.filename))');
           else
              uialert(app.UIFigure,'File not found','Invalid File');
           end
           
           % Here I want to throw feedback info back to the UI window textbox
           app.runlogTextArea.Value = T;
           app.FileDirectory.Value = strcat(app.pathname,app.filename);
           
           % Set slider range based on input
           refreshslider(app)
           
        end

        % Value changed function: plotmodeDropDown
        function plotmodeChanged(app, event)
            app.plotmode = app.plotmodeDropDown.Value;
            
        end

        % Value changed function: userrangeCheckBox, xmaxEditField, 
        % xminEditField, ymaxEditField, yminEditField
        function plotrangeChanged(app, event)
           if ~app.userrangeCheckBox.Value
              app.xminEditField.Visible = 'off'; 
              app.xminEditFieldLabel.Visible = 'off';
              app.xmaxEditField.Visible = 'off'; 
              app.xmaxEditField_2Label.Visible = 'off';
              app.yminEditField.Visible = 'off'; 
              app.yminEditFieldLabel.Visible = 'off';
              app.ymaxEditField.Visible = 'off'; 
              app.ymaxEditFieldLabel.Visible = 'off';
           else
              app.xminEditField.Visible = 'on';
              app.xminEditFieldLabel.Visible = 'on';
              app.xmaxEditField.Visible = 'on'; 
              app.xmaxEditField_2Label.Visible = 'on';
              app.yminEditField.Visible = 'on'; 
              app.yminEditFieldLabel.Visible = 'on';
              app.ymaxEditField.Visible = 'on'; 
              app.ymaxEditFieldLabel.Visible = 'on';            
           end
            
            
           if ~app.userrangeCheckBox.Value
              app.plotrange(1) = app.xminEditField.Value;
              app.plotrange(2) = app.xmaxEditField.Value;
              app.plotrange(3) = app.yminEditField.Value;
              app.plotrange(4) = app.ymaxEditField.Value;
           end
        end

        % Value changed function: plotvarEditField
        function plotvarChanged(app, event)
            app.func = app.plotvarEditField.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           if app.Plottype1D.Value
              plot_log_data(app.Data,app.filehead,app.func, ...
                 'plotmode','line');
           elseif app.Plottype2D.Value
              if isempty(app.plotmode)
                 app.plotmode = 'cont'; 
              end
              
              % I cannot plot in the UIAxes, which is absurd...
              
              if isempty(app.ipict)
                app.ipict = 1; 
              end
              
              [~,app.Data] = read_data(strcat(app.pathname,app.filename),...
                  'npict',app.ipict);
              
              plot_data(app.Data.file1,app.filehead,app.func,...
                 'plotmode',app.plotmode,...
                 'plotrange',app.plotrange,...
                 'plotinterval',app.plotinterval);
           elseif app.Plottype3D.Value
              uialert(app.UIFigure,'3D visualization not yet implemented',...
                  'Invalid Plot type');
           end
           
           % Send information to output display window
           %app.runlogTextArea.Value = T;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           % I need to add something here.
           delete(app)
        end

        % Value changing function: ipictSlider
        function ipictSliderValueChanging(app, event)
            changingValue = round(event.Value);
            if app.filelist.npictinfiles > 1
               app.ipict = changingValue;
               app.ipictLabel.Text = strcat('ipict = ',num2str(changingValue));
            else
               app.ipictLabel.Text = strcat('ipict = 1');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'VisAna';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReadTab = uitab(app.TabGroup);
            app.ReadTab.Title = 'Read';
            app.SelectFilesButton = uibutton(app.ReadTab, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadData, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.runlogTextAreaLabel = uilabel(app.ReadTab);
            app.runlogTextAreaLabel.Text = 'runlog';
            app.runlogTextArea = uitextarea(app.ReadTab);
            app.runlogTextArea.ValueChangedFcn = createCallbackFcn(app, @ReadData, true);
            app.FileDirectory = uieditfield(app.ReadTab, 'text');
            app.FileDirectory.ValueChangedFcn = createCallbackFcn(app, @ReadData, true);
            app.FileDirectory.Editable = 'off';
            app.TabPlot = uitab(app.TabGroup);
            app.TabPlot.Title = 'Plot';
            app.UIAxes = uiaxes(app.TabPlot);
            xlabel(app.UIAxes, 'Weight')
            ylabel(app.UIAxes, 'Diastolic')
            app.Label = uilabel(app.TabPlot);
            app.Label.Text = 'Data Visualization';
            app.Panel = uipanel(app.TabPlot);
            app.yminEditFieldLabel = uilabel(app.Panel);
            app.yminEditFieldLabel.Text = 'ymin';
            app.yminEditField = uieditfield(app.Panel, 'numeric');
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @plotrangeChanged, true);
            app.xminEditFieldLabel = uilabel(app.Panel);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.Panel, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @plotrangeChanged, true);
            app.xmaxEditField_2Label = uilabel(app.Panel);
            app.xmaxEditField_2Label.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.Panel, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @plotrangeChanged, true);
            app.ymaxEditFieldLabel = uilabel(app.Panel);
            app.ymaxEditFieldLabel.Text = 'ymax';
            app.ymaxEditField = uieditfield(app.Panel, 'numeric');
            app.ymaxEditField.ValueChangedFcn = createCallbackFcn(app, @plotrangeChanged, true);
            app.autoredrawCheckBox = uicheckbox(app.Panel);
            app.autoredrawCheckBox.Text = 'auto redraw';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.userrangeCheckBox = uicheckbox(app.Panel);
            app.userrangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotrangeChanged, true);
            app.userrangeCheckBox.Text = 'user range';
            app.plotvarEditFieldLabel = uilabel(app.Panel);
            app.plotvarEditFieldLabel.Text = 'plotvar';
            app.plotvarEditField = uieditfield(app.Panel, 'text');
            app.plotvarEditField.ValueChangedFcn = createCallbackFcn(app, @plotvarChanged, true);
            app.plotmodeDropDownLabel = uilabel(app.Panel);
            app.plotmodeDropDownLabel.Text = 'plotmode';
            app.plotmodeDropDown = uidropdown(app.Panel);
            app.plotmodeDropDown.Items = {'cont', 'contbar', 'contbarlog', 'mesh', 'meshbar', 'meshbarlog', 'contbar streamover', 'grid', 'Option 4'};
            app.plotmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @plotmodeChanged, true);
            app.plotmodeDropDown.Value = 'cont';
            app.PlottypeButtonGroup = uibuttongroup(app.TabPlot);
            app.PlottypeButtonGroup.Title = 'Plot type';
            app.Plottype1D = uitogglebutton(app.PlottypeButtonGroup);
            app.Plottype1D.Text = '1D';
            app.Plottype1D.Value = true;
            app.Plottype2D = uitogglebutton(app.PlottypeButtonGroup);
            app.Plottype2D.Text = '2D';
            app.Plottype3D = uitogglebutton(app.PlottypeButtonGroup);
            app.Plottype3D.Text = '3D';
            app.ipictLabel = uilabel(app.TabPlot);
            app.ipictLabel.Text = 'ipict';
            app.ipictSlider = uislider(app.TabPlot);
            app.ipictSlider.Limits = [1 9];
            app.ipictSlider.MajorTicks = [];
            app.ipictSlider.MajorTickLabels = {};
            app.ipictSlider.ValueChangingFcn = createCallbackFcn(app, @ipictSliderValueChanging, true);
            app.ipictSlider.MinorTicks = [];
            app.ipictSlider.Value = 1;
            app.TabData = uitab(app.TabGroup);
            app.TabData.Title = 'Data';
            app.UITable = uitable(app.TabData);
            app.UITable.ColumnName = {'Last Name'; 'Gender'; 'Smoker'; 'Age'; 'Height'; 'Weight'; 'Diastolic'; 'Systolic'; 'Location'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @refreshplot, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = VisAnaGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end